classdef GravimeterControlInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GravimeterControlInterfaceUIFigure  matlab.ui.Figure
        GravimeterControlInterfaceLabel  matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        CoolingTab                     matlab.ui.container.Tab
        InitialiseControlsButton       matlab.ui.control.Button
        DirectorytypeSwitch            matlab.ui.control.ToggleSwitch
        DirectorytypeSwitchLabel       matlab.ui.control.Label
        HelpButton                     matlab.ui.control.Button
        ManualsaveButton               matlab.ui.control.Button
        SelectButton_2                 matlab.ui.control.Button
        RunningLampLabel               matlab.ui.control.Label
        TextArea_2                     matlab.ui.control.TextArea
        TextArea                       matlab.ui.control.TextArea
        OpenButton_2                   matlab.ui.control.Button
        SequenceMakerTextArea          matlab.ui.control.TextArea
        SequenceMakerLabel             matlab.ui.control.Label
        OpenButton                     matlab.ui.control.Button
        SelectButton                   matlab.ui.control.Button
        CallbackfunctionTextArea       matlab.ui.control.TextArea
        CallbackfunctionLabel_2        matlab.ui.control.Label
        AbortButton                    matlab.ui.control.Button
        AbsAnalysisGUIButton           matlab.ui.control.Button
        DebugPortsButton               matlab.ui.control.Button
        DisplaySequenceGUIButton       matlab.ui.control.Button
        MTSLockingButton               matlab.ui.control.Button
        UploadValuesButton             matlab.ui.control.Button
        UploadRunButton                matlab.ui.control.Button
        ScreenshotButton               matlab.ui.control.Button
        NameEditField                  matlab.ui.control.EditField
        NameEditFieldLabel             matlab.ui.control.Label
        DirectoryEditField             matlab.ui.control.EditField
        DirectoryEditFieldLabel        matlab.ui.control.Label
        StatusLamp_2                   matlab.ui.control.Lamp
        SaveDataSwitch                 matlab.ui.control.ToggleSwitch
        SaveDataSwitchLabel            matlab.ui.control.Label
        RestartButton                  matlab.ui.control.Button
        DecrementButton                matlab.ui.control.Button
        IncrementButton                matlab.ui.control.Button
        ResetButton                    matlab.ui.control.Button
        StopButton                     matlab.ui.control.Button
        ContinuousRunButton            matlab.ui.control.Button
        StartButton                    matlab.ui.control.Button
        MOTLoadtimesEditField          matlab.ui.control.NumericEditField
        MOTLoadtimesEditFieldLabel     matlab.ui.control.Label
        ChangeButton                   matlab.ui.control.Button
        CallbackfunctionLabel          matlab.ui.control.Label
        RunexecutionLabel              matlab.ui.control.Label
        ToolsAccessLabel               matlab.ui.control.Label
        RunningLamp                    matlab.ui.control.Lamp
        F2m_f0rightarrowF1m_f0EditField  matlab.ui.control.NumericEditField
        F2m_f0rightarrowF1m_f0EditFieldLabel  matlab.ui.control.Label
        F1m_f1rightarrowF2m_f0EditField  matlab.ui.control.NumericEditField
        ketF1m_f1rightarrowLabel       matlab.ui.control.Label
        VariableParameterEditField     matlab.ui.control.NumericEditField
        VariableParameterEditFieldLabel  matlab.ui.control.Label
        SingleRunButton                matlab.ui.control.Button
        MagneticInsenstivestateTransferSwitch  matlab.ui.control.Switch
        MagneticInsenstivestateTransferSwitchLabel  matlab.ui.control.Label
        BECCompressionSwitch           matlab.ui.control.Switch
        BECCompressionSwitchLabel      matlab.ui.control.Label
        OpticalEvaporationSwitch       matlab.ui.control.Switch
        OpticalEvaporationSwitchLabel  matlab.ui.control.Label
        LoadOpticalTrapSwitch          matlab.ui.control.Switch
        LoadOpticalTrapSwitchLabel     matlab.ui.control.Label
        MagEvaporationSwitch           matlab.ui.control.Switch
        MagEvaporationSwitchLabel      matlab.ui.control.Label
        LoadMagTrapSwitch              matlab.ui.control.Switch
        LoadMagTrapSwitchLabel         matlab.ui.control.Label
        PGCSwitch                      matlab.ui.control.Switch
        PGCSwitchLabel                 matlab.ui.control.Label
        CMOTSwitch                     matlab.ui.control.Switch
        CMOTSwitchLabel                matlab.ui.control.Label
        MOTSwitch                      matlab.ui.control.Switch
        MOTSwitchLabel                 matlab.ui.control.Label
        CoolingSequenceLabel           matlab.ui.control.Label
        OptionsLabel                   matlab.ui.control.Label
        UploadOptionsButton            matlab.ui.control.Button
        DipolesWEditField              matlab.ui.control.NumericEditField
        DipolesWEditFieldLabel         matlab.ui.control.Label
        TimeofflightmsEditField        matlab.ui.control.NumericEditField
        TimeofflightmsEditFieldLabel   matlab.ui.control.Label
        DetuningMHzEditField           matlab.ui.control.NumericEditField
        DetuningMHzLabel               matlab.ui.control.Label
        InterferometryTab              matlab.ui.container.Tab
        ComingSoonLabel_2              matlab.ui.control.Label
    end


    properties (Access = private)
        singleRunTimer % timer for single run
        continuousRunTimer % timer for continuous run
        startRunTimer % timer for start run
        components % Components to enable/disable

    end

    methods (Access = private)
        % Callbacks that handle component events
        function setup(app)

            app.components = findall(app.GravimeterControlInterfaceUIFigure, '-property', 'Enable');
        end
        function disableAllComponentsExceptAbort(app)
            % Disables all components except the AbortButton
            app.components = findall(app.GravimeterControlInterfaceUIFigure, '-property', 'Enable');
            for comp = app.components'
                if comp ~= app.AbortButton
                    comp.Enable = 'off';
                end
            end
            % Turn status lamp to orange and indicate standby
            app.RunningLamp.Color = 'green';
            app.RunningLampLabel.Text = 'Running';

        end

        function enableAllComponents(app)
            % Enables all components
            app.components = findall(app.GravimeterControlInterfaceUIFigure, '-property', 'Enable');
            for comp = app.components'
                comp.Enable = 'on';
            end

            % Turn status lamp to orange and indicate standby
            app.RunningLamp.Color = [1 0.5 0];  % Orange in RGB
            app.RunningLampLabel.Text = 'Standby';
        end


        function checkRunStatus(app)
            % Check if the run has ended by monitoring 'CycleEnded' variable in the base workspace
            cycleEnded = evalin('base', 'CycleEnded'); %ok so this is a trigger I installed

            if cycleEnded
                % If the cycle has ended, stop the timer
                stop(app.singleRunTimer);
                delete(app.singleRunTimer);

                % Enable all interactive components
                enableAllComponents(app);
                % Disable the MOT switch
                set(app.MOTSwitch, 'Enable', 'off');

                % Turn status lamp to orange and indicate standby
                app.RunningLamp.Color = [1 0.5 0];  % Orange in RGB
                app.RunningLampLabel.Text = 'Standby';
            end
        end


        function disableSpecificComponents(app, componentsToDisable)
            % Disable specific components
            for compName = componentsToDisable
                app.(compName{1}).Enable = 'off';
            end
        end

        function enableSpecificComponents(app, componentsToEnable)
            % Enable specific components
            for compName = componentsToEnable
                app.(compName{1}).Enable = 'on';
            end
        end
        function timerBlink(app)
            if isequal(app.RunningLamp.Color,[1, 0, 0])
                app.RunningLamp.Color = [0 0 0];  % change color to black
            else
                app.RunningLamp.Color = [1 0 0];  % change color to red
            end
        end


    end

    % Static methods
    methods (Static)
        function str = numToSwitch(num)
            if num == 1
                str = 'On';
            else
                str = 'Off';
            end
        end

        function num = switchToNum(str)
            if strcmp(str, 'On')
                num = 1;
            else
                num = 0;
            end
        end

    end
    
    methods (Access = public)

        function triggerFromOutside(app)
            % Use the lamp status
            if strcmp(app.SaveDataSwitch.Value, "On")
                app.RunningLamp.Color = 'blue';
                app.RunningLampLabel.Text = 'Saving';

                r = evalin('base','r');
                data = r.data;
                pause(.3)
                assignin('base','data',data);

                % Get current date and time
                dateTimeNow = datestr(datetime('now'),'yy-mm-dd-HH-MM-SS');

                if strcmp(app.DirectorytypeSwitch.Value, 'Default')
                    % Create the directory
                    directory = ['E:\data\', datestr(datetime('now'),'yy-mm-dd')];
                    if ~exist(directory, 'dir')
                        mkdir(directory)
                    end

                    % Get the filename
                    filename = getFileName(app, directory);

                elseif strcmp(app.DirectorytypeSwitch.Value, 'Custom')
                    directory = app.DirectoryEditField.Value;
                    if isempty(directory)
                        directory = uigetdir('E:\data\', 'Select a directory');
                        % Update the DirectoryEditField with the selected directory
                        app.DirectoryEditField.Value = directory;
                    end

                    filename = getFileName(app, directory);
                end

                % Save the file
                filename = [directory, '\', dateTimeNow, '_', filename, '.mat'];
                save(filename, 'data');
                disp(['File ', filename, ' was saved in ', directory])
                app.RunningLamp.Color = [1 0.5 0];  % Orange in RGB
                app.RunningLampLabel.Text = 'Standby';
                enableAllComponents(app);

                % Disable the MOT switch and directory selection
                app.MOTSwitch.Enable = 'off';
                if strcmp(app.DirectorytypeSwitch.Value, 'Default')
                    app.SelectButton_2.Text = 'Open';
                elseif strcmp(app.DirectorytypeSwitch.Value, 'Custom')
                    app.SelectButton_2.Text = 'Select';
                end

                %case the autosave is not on
            else
                app.RunningLamp.Color = [1 0.5 0];  % Orange in RGB
                app.RunningLampLabel.Text = 'Standby';
                enableAllComponents(app);

                % Disable the MOT switch and directory selection
                app.MOTSwitch.Enable = 'off';
                if strcmp(app.DirectorytypeSwitch.Value, 'Default')
                    app.SelectButton_2.Text = 'Open';
                elseif strcmp(app.DirectorytypeSwitch.Value, 'Custom')
                    app.SelectButton_2.Text = 'Select';
                end
            end
        end

        function filename = getFileName(app, directory)
            if isempty(app.NameEditField.Value)
                filename = inputdlg('Enter the filename:');
                filename = filename{1};
            else
                files = dir(fullfile(directory,'*.mat'));
                baseNames = cellfun(@(s) extractAfter(s, 18), {files.name}, 'UniformOutput', false);
                baseNames = erase(baseNames, '.mat');
                if any(strcmp(app.NameEditField.Value, baseNames))
                    choice = questdlg('A file with this base name already exists. Do you want to change it?', ...
                        'File Exists', ...
                        'Yes', 'No', 'No');
                    switch choice
                        case 'Yes'
                            filename = inputdlg('Enter a new filename:');
                            filename = filename{1};
                        case 'No'
                            filename = app.NameEditField.Value;
                    end
                else
                    filename = app.NameEditField.Value;
                end
            end
        end


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


            % Turn status lamp to green and indicate running
            app.RunningLamp.Color = [1 0 0];  % Orange in RGB
            app.RunningLampLabel.Text = 'Initialising';
            app.RunningLampLabel.HorizontalAlignment = 'center';
            app.RunningLampLabel.FontName = 'Chalkboard';
            app.RunningLampLabel.FontSize = 18;
            app.RunningLampLabel.FontColor = [1 1 1];
            app.RunningLampLabel.Position = [924 303 89 32];

            set(app.GravimeterControlInterfaceUIFigure, 'UserData', app);
            % Get all interactive components
            app.components = findall(app.GravimeterControlInterfaceUIFigure, '-property', 'Enable');

            % Disable all interactive components
            set(app.components, 'Enable', 'off');

            set(app.RunningLamp, 'Enable', 'on');
            set(app.RunningLampLabel, 'Enable', 'on');

            % Create timer
            t = timer;
            t.StartDelay = .1;
            t.Period = 0.7;
            t.ExecutionMode = 'fixedRate';
            t.TimerFcn = @(~,~) timerBlink(app);
            start(t);

            % Run InitialiseControls
            pause(1.5)
            initialiseControls;

            %stop the time and delete it
            stop(t);
            delete(t);  % delete the timer


            % 'opt' is in the base workspace and contains properties 'detuning', 'dipoles', 'time_of_flight', 'MOT_Load', 'Variable_parameters'
            opt = evalin('base', 'opt');

            app.DetuningMHzEditField.Value = opt.detuning;
            app.DipolesWEditField.Value = opt.dipoles;
            app.TimeofflightmsEditField.Value = opt.tof*1e3;
            app.MOTLoadtimesEditField.Value = opt.MOT_LoadTime;
            %             app.VariableParameterEditField.Value = opt.params; this is
            %             not taken as en empty ensemble from the numerical field.


            %stage initialisation
            app.MOTSwitch.Value = app.numToSwitch(opt.MOT_status);
            app.CMOTSwitch.Value = app.numToSwitch(opt.CMOT_status);
            app.PGCSwitch.Value = app.numToSwitch(opt.PGC_status);
            app.LoadMagTrapSwitch.Value = app.numToSwitch(opt.LoadMagTrap_status);
            app.MagEvaporationSwitch.Value = app.numToSwitch(opt.MagEvaporation_status);
            app.LoadOpticalTrapSwitch.Value = app.numToSwitch(opt.LoadOpticalTrap_status);
            app.OpticalEvaporationSwitch.Value = app.numToSwitch(opt.OpticalEvaporation_status);
            app.BECCompressionSwitch.Value = app.numToSwitch(opt.BECCompression_status);
            app.MagneticInsenstivestateTransferSwitch.Value = app.numToSwitch(opt.MagneticInsensitive_status);


            %mku device frequency initialisation
            m = evalin('base','m');
            app.F1m_f1rightarrowF2m_f0EditField.Value = (2*m.f1/1e6-const.f_Rb_groundHFS/1e6)*1e3;
            app.F2m_f0rightarrowF1m_f0EditField.Value = (2*m.f2/1e6-const.f_Rb_groundHFS/1e6)*1e6;

            % After InitialiseControls finishes, re-enable all components
            set(app.components, 'Enable', 'on');


            % Disable the MOT switch
            set(app.MOTSwitch, 'Enable', 'off');
            % set the select 2 switch to open
            app.SelectButton_2.Text = 'Open';
            set(app.DirectoryEditField,'Editable','off')

            % % Get the 'makerCallback' from the 'r' object in the workspace
            r = evalin('base', 'r');  % Retrieve r object from the base workspace
            app.SequenceMakerTextArea.Value = func2str(r.makerCallback);
            if ~isempty(r.callback)
                app.CallbackfunctionTextArea.Value = func2str(r.callback);
            end
            %inital color of the Lamp of the save data toggle!
            app.StatusLamp_2.Color = [1, 0.5, 0];

            % Turn status lamp to orange and indicate standbye
            app.RunningLamp.Color = [1 0.5 0];  % Orange in RGB
            app.RunningLampLabel.Text = 'Standby';
            app.RunningLampLabel.HorizontalAlignment = 'center';
            app.RunningLampLabel.FontName = 'Chalkboard';
            app.RunningLampLabel.FontSize = 24;
            app.RunningLampLabel.FontColor = [1 1 1];
            app.RunningLampLabel.Position = [924 303 89 32];


        end

        % Value changed function: LoadMagTrapSwitch
        function LoadMagTrapSwitchValueChanged(app, event)

            if app.LoadMagTrapSwitch.Value == "Off"
                app.MagEvaporationSwitch.Value = "Off";
                app.LoadOpticalTrapSwitch.Value = "Off";
                app.OpticalEvaporationSwitch.Value = "Off";
                app.BECCompressionSwitch.Value = "Off";
                app.MagneticInsenstivestateTransferSwitch.Value = "Off";

            elseif app.LoadMagTrapSwitch.Value == "On"
                app.CMOTSwitch.Value = "On";
                app.PGCSwitch.Value = "On";
            end

            % Updating the cooling sequence in the workspace assuming 'opt' is in the base workspace
            opt = evalin('base', 'opt');

            opt.MOT_status = app.switchToNum(app.MOTSwitch.Value);
            opt.CMOT_status = app.switchToNum(app.CMOTSwitch.Value);
            opt.PGC_status = app.switchToNum(app.PGCSwitch.Value);
            opt.LoadMagTrap_status = app.switchToNum(app.LoadMagTrapSwitch.Value);
            opt.MagEvaporation_status = app.switchToNum(app.MagEvaporationSwitch.Value);
            opt.LoadOpticalTrap_status = app.switchToNum(app.LoadOpticalTrapSwitch.Value);
            opt.OpticalEvaporation_status = app.switchToNum(app.OpticalEvaporationSwitch.Value);
            opt.BECCompression_status = app.switchToNum(app.BECCompressionSwitch.Value);
            opt.MagneticInsensitive_status = app.switchToNum(app.MagneticInsenstivestateTransferSwitch.Value);

            % Update 'opt' in the base workspace
            assignin('base', 'opt', opt);
        end

        % Value changed function: MOTSwitch
        function MOTSwitchValueChanged(app, event)
            if app.MOTSwitch.Value == "Off"
                app.CMOTSwitch.Value = "Off";
                app.PGCSwitch.Value = "Off";
                app.LoadMagTrapSwitch.Value = "Off";
                app.MagEvaporationSwitch.Value = "Off";
                app.LoadOpticalTrapSwitch.Value = "Off";
                app.OpticalEvaporationSwitch.Value = "Off";
                app.BECCompressionSwitch.Value = "Off";
                app.MagneticInsenstivestateTransferSwitch.Value = "Off";
            end

            % Updating the cooling sequence in the workspace assuming 'opt' is in the base workspace
            opt = evalin('base', 'opt');

            opt.MOT_status = app.switchToNum(app.MOTSwitch.Value);
            opt.CMOT_status = app.switchToNum(app.CMOTSwitch.Value);
            opt.PGC_status = app.switchToNum(app.PGCSwitch.Value);
            opt.LoadMagTrap_status = app.switchToNum(app.LoadMagTrapSwitch.Value);
            opt.MagEvaporation_status = app.switchToNum(app.MagEvaporationSwitch.Value);
            opt.LoadOpticalTrap_status = app.switchToNum(app.LoadOpticalTrapSwitch.Value);
            opt.OpticalEvaporation_status = app.switchToNum(app.OpticalEvaporationSwitch.Value);
            opt.BECCompression_status = app.switchToNum(app.BECCompressionSwitch.Value);
            opt.MagneticInsensitive_status = app.switchToNum(app.MagneticInsenstivestateTransferSwitch.Value);

            % Update 'opt' in the base workspace
            assignin('base', 'opt', opt);

        end

        % Value changed function: CMOTSwitch
        function CMOTSwitchValueChanged(app, event)
            if app.CMOTSwitch.Value == "Off"
                app.PGCSwitch.Value = "Off";
                app.LoadMagTrapSwitch.Value = "Off";
                app.MagEvaporationSwitch.Value = "Off";
                app.LoadOpticalTrapSwitch.Value = "Off";
                app.OpticalEvaporationSwitch.Value = "Off";
                app.BECCompressionSwitch.Value = "Off";
                app.MagneticInsenstivestateTransferSwitch.Value = "Off";

                % Updating the cooling sequence in the workspace assuming 'opt' is in the base workspace
                opt = evalin('base', 'opt');
                opt.MOT_status = app.switchToNum(app.MOTSwitch.Value);
                opt.CMOT_status = app.switchToNum(app.CMOTSwitch.Value);
                opt.PGC_status = app.switchToNum(app.PGCSwitch.Value);
                opt.LoadMagTrap_status = app.switchToNum(app.LoadMagTrapSwitch.Value);
                opt.MagEvaporation_status = app.switchToNum(app.MagEvaporationSwitch.Value);
                opt.LoadOpticalTrap_status = app.switchToNum(app.LoadOpticalTrapSwitch.Value);
                opt.OpticalEvaporation_status = app.switchToNum(app.OpticalEvaporationSwitch.Value);
                opt.BECCompression_status = app.switchToNum(app.BECCompressionSwitch.Value);
                opt.MagneticInsensitive_status = app.switchToNum(app.MagneticInsenstivestateTransferSwitch.Value);
                % Update 'opt' in the base workspace
                assignin('base', 'opt', opt);
            end

        end

        % Value changed function: PGCSwitch
        function PGCSwitchValueChanged(app, event)
            if app.PGCSwitch.Value == "Off"
                app.LoadMagTrapSwitch.Value = "Off";
                app.MagEvaporationSwitch.Value = "Off";
                app.LoadOpticalTrapSwitch.Value = "Off";
                app.OpticalEvaporationSwitch.Value = "Off";
                app.BECCompressionSwitch.Value = "Off";
                app.MagneticInsenstivestateTransferSwitch.Value = "Off";
            elseif app.PGCSwitch.Value == "On"
                app.CMOTSwitch.Value = "On";
            end
            % Updating the cooling sequence in the workspace assuming 'opt' is in the base workspace
            opt = evalin('base', 'opt');

            opt.MOT_status = app.switchToNum(app.MOTSwitch.Value);
            opt.CMOT_status = app.switchToNum(app.CMOTSwitch.Value);
            opt.PGC_status = app.switchToNum(app.PGCSwitch.Value);
            opt.LoadMagTrap_status = app.switchToNum(app.LoadMagTrapSwitch.Value);
            opt.MagEvaporation_status = app.switchToNum(app.MagEvaporationSwitch.Value);
            opt.LoadOpticalTrap_status = app.switchToNum(app.LoadOpticalTrapSwitch.Value);
            opt.OpticalEvaporation_status = app.switchToNum(app.OpticalEvaporationSwitch.Value);
            opt.BECCompression_status = app.switchToNum(app.BECCompressionSwitch.Value);
            opt.MagneticInsensitive_status = app.switchToNum(app.MagneticInsenstivestateTransferSwitch.Value);

            % Update 'opt' in the base workspace
            assignin('base', 'opt', opt);

        end

        % Value changed function: MagEvaporationSwitch
        function MagEvaporationSwitchValueChanged(app, event)
            if app.MagEvaporationSwitch.Value == "Off"
                app.LoadOpticalTrapSwitch.Value = "Off";
                app.OpticalEvaporationSwitch.Value = "Off";
                app.BECCompressionSwitch.Value = "Off";
                app.MagneticInsenstivestateTransferSwitch.Value = "Off";
            elseif app.MagEvaporationSwitch.Value == "On"
                app.CMOTSwitch.Value = "On";
                app.PGCSwitch.Value = "On";
                app.LoadMagTrapSwitch.Value = "On";

            end
            % Updating the cooling sequence in the workspace assuming 'opt' is in the base workspace
            opt = evalin('base', 'opt');

            opt.MOT_status = app.switchToNum(app.MOTSwitch.Value);
            opt.CMOT_status = app.switchToNum(app.CMOTSwitch.Value);
            opt.PGC_status = app.switchToNum(app.PGCSwitch.Value);
            opt.LoadMagTrap_status = app.switchToNum(app.LoadMagTrapSwitch.Value);
            opt.MagEvaporation_status = app.switchToNum(app.MagEvaporationSwitch.Value);
            opt.LoadOpticalTrap_status = app.switchToNum(app.LoadOpticalTrapSwitch.Value);
            opt.OpticalEvaporation_status = app.switchToNum(app.OpticalEvaporationSwitch.Value);
            opt.BECCompression_status = app.switchToNum(app.BECCompressionSwitch.Value);
            opt.MagneticInsensitive_status = app.switchToNum(app.MagneticInsenstivestateTransferSwitch.Value);
            % Update 'opt' in the base workspace
            assignin('base', 'opt', opt);

        end

        % Value changed function: LoadOpticalTrapSwitch
        function LoadOpticalTrapSwitchValueChanged(app, event)
            if app.LoadOpticalTrapSwitch.Value == "Off"
                app.OpticalEvaporationSwitch.Value = "Off";
                app.BECCompressionSwitch.Value = "Off";
                app.MagneticInsenstivestateTransferSwitch.Value = "Off";
            elseif app.LoadOpticalTrapSwitch.Value == "On"
                app.CMOTSwitch.Value = "On";
                app.PGCSwitch.Value = "On";
                app.LoadMagTrapSwitch.Value = "On";
                app.MagEvaporationSwitch.Value = "On";

            end

            % Updating the cooling sequence in the workspace assuming 'opt' is in the base workspace
            opt = evalin('base', 'opt');

            opt.MOT_status = app.switchToNum(app.MOTSwitch.Value);
            opt.CMOT_status = app.switchToNum(app.CMOTSwitch.Value);
            opt.PGC_status = app.switchToNum(app.PGCSwitch.Value);
            opt.LoadMagTrap_status = app.switchToNum(app.LoadMagTrapSwitch.Value);
            opt.MagEvaporation_status = app.switchToNum(app.MagEvaporationSwitch.Value);
            opt.LoadOpticalTrap_status = app.switchToNum(app.LoadOpticalTrapSwitch.Value);
            opt.OpticalEvaporation_status = app.switchToNum(app.OpticalEvaporationSwitch.Value);
            opt.BECCompression_status = app.switchToNum(app.BECCompressionSwitch.Value);
            opt.MagneticInsensitive_status = app.switchToNum(app.MagneticInsenstivestateTransferSwitch.Value);

            % Update 'opt' in the base workspace
            assignin('base', 'opt', opt);

        end

        % Value changed function: OpticalEvaporationSwitch
        function OpticalEvaporationSwitchValueChanged(app, event)
            if app.OpticalEvaporationSwitch.Value == "Off"
                app.BECCompressionSwitch.Value = "Off";
                app.MagneticInsenstivestateTransferSwitch.Value = "Off";
            elseif app.OpticalEvaporationSwitch.Value == "On"
                app.CMOTSwitch.Value = "On";
                app.PGCSwitch.Value = "On";
                app.LoadMagTrapSwitch.Value = "On";
                app.MagEvaporationSwitch.Value = "On";
                app.LoadOpticalTrapSwitch.Value = "On";
            end
            % Updating the cooling sequence in the workspace assuming 'opt' is in the base workspace
            opt = evalin('base', 'opt');

            opt.MOT_status = app.switchToNum(app.MOTSwitch.Value);
            opt.CMOT_status = app.switchToNum(app.CMOTSwitch.Value);
            opt.PGC_status = app.switchToNum(app.PGCSwitch.Value);
            opt.LoadMagTrap_status = app.switchToNum(app.LoadMagTrapSwitch.Value);
            opt.MagEvaporation_status = app.switchToNum(app.MagEvaporationSwitch.Value);
            opt.LoadOpticalTrap_status = app.switchToNum(app.LoadOpticalTrapSwitch.Value);
            opt.OpticalEvaporation_status = app.switchToNum(app.OpticalEvaporationSwitch.Value);
            opt.BECCompression_status = app.switchToNum(app.BECCompressionSwitch.Value);
            opt.MagneticInsensitive_status = app.switchToNum(app.MagneticInsenstivestateTransferSwitch.Value);


            % Update 'opt' in the base workspace
            assignin('base', 'opt', opt);

        end

        % Value changed function: BECCompressionSwitch
        function BECCompressionSwitchValueChanged(app, event)
            if app.BECCompressionSwitch.Value == "On"
                app.CMOTSwitch.Value = "On";
                app.PGCSwitch.Value = "On";
                app.LoadMagTrapSwitch.Value = "On";
                app.MagEvaporationSwitch.Value = "On";
                app.LoadOpticalTrapSwitch.Value = "On";
                app.OpticalEvaporationSwitch.Value = "On";
            end
            % Updating the cooling sequence in the workspace assuming 'opt' is in the base workspace
            opt = evalin('base', 'opt');

            opt.MOT_status = app.switchToNum(app.MOTSwitch.Value);
            opt.CMOT_status = app.switchToNum(app.CMOTSwitch.Value);
            opt.PGC_status = app.switchToNum(app.PGCSwitch.Value);
            opt.LoadMagTrap_status = app.switchToNum(app.LoadMagTrapSwitch.Value);
            opt.MagEvaporation_status = app.switchToNum(app.MagEvaporationSwitch.Value);
            opt.LoadOpticalTrap_status = app.switchToNum(app.LoadOpticalTrapSwitch.Value);
            opt.OpticalEvaporation_status = app.switchToNum(app.OpticalEvaporationSwitch.Value);
            opt.BECCompression_status = app.switchToNum(app.BECCompressionSwitch.Value);
            opt.MagneticInsensitive_status = app.switchToNum(app.MagneticInsenstivestateTransferSwitch.Value);

            % Update 'opt' in the base workspace
            assignin('base', 'opt', opt);

        end

        % Value changed function: MagneticInsenstivestateTransferSwitch
        function MagneticInsenstivestateTransferSwitchValueChanged(app, event)
            if app.MagneticInsenstivestateTransferSwitch.Value == "On"
                app.CMOTSwitch.Value = "On";
                app.PGCSwitch.Value = "On";
                app.LoadMagTrapSwitch.Value = "On";
                app.MagEvaporationSwitch.Value = "On";
                app.LoadOpticalTrapSwitch.Value = "On";
                app.OpticalEvaporationSwitch.Value = "On";
            end

            % Updating the cooling sequence in the workspace assuming 'opt' is in the base workspace
            opt = evalin('base', 'opt');

            opt.MOT_status = app.switchToNum(app.MOTSwitch.Value);
            opt.CMOT_status = app.switchToNum(app.CMOTSwitch.Value);
            opt.PGC_status = app.switchToNum(app.PGCSwitch.Value);
            opt.LoadMagTrap_status = app.switchToNum(app.LoadMagTrapSwitch.Value);
            opt.MagEvaporation_status = app.switchToNum(app.MagEvaporationSwitch.Value);
            opt.LoadOpticalTrap_status = app.switchToNum(app.LoadOpticalTrapSwitch.Value);
            opt.OpticalEvaporation_status = app.switchToNum(app.OpticalEvaporationSwitch.Value);
            opt.BECCompression_status = app.switchToNum(app.BECCompressionSwitch.Value);
            opt.MagneticInsensitive_status = app.switchToNum(app.MagneticInsenstivestateTransferSwitch.Value);

            % Update 'opt' in the base workspace
            assignin('base', 'opt', opt);

        end

        % Button pushed function: UploadOptionsButton
        function UploadOptionsButtonPushed(app, event)
            % Assuming 'opt' is in the base workspace
            opt = evalin('base', 'opt');

            opt.detuning = app.DetuningMHzEditField.Value;
            opt.dipoles = app.DipolesWEditField.Value;
            opt.tof = 1e-3*app.TimeofflightmsEditField.Value;
            opt.MOT_LoadTime = app.MOTLoadtimesEditField.Value;
            opt.params = app.VariableParameterEditField.Value;

            % Update 'opt' in the base workspace
            assignin('base', 'opt', opt);
            disp('New options edited')

        end

        % Button pushed function: MTSLockingButton
        function MTSLockingButtonPushed(app, event)

            % Check if the LaserServo window is already open
            existingFigures = findall(groot, 'Type', 'figure'); % Get all open figures

            isOpen = any(strcmp({existingFigures.Name}, 'Laser Servo Control')); % Check if 'LaserServo' is already open

            if isOpen
                % If the window is already open, print a message
                fprintf('The LaserServo window is already open.\n');
            else
                % If the window is not open, execute the ControlGUI function
                IPaddresses = evalin('base', 'IPaddresses');
                ControlGUI(IPaddresses.MTS);
                disp('Laser servo opened')
            end

        end

        % Button pushed function: DisplaySequenceGUIButton
        function DisplaySequenceGUIButtonPushed(app, event)
            % Check if the FIGURE UI window is already open
            existingFigures = findall(groot, 'Type', 'Figure'); % Get all open figures
            isOpen = any(strcmp({existingFigures.Name}, 'UI Figure')); % Check if 'FIGURE UI' is already open

            if isOpen
                % If the window is already open, print a message
                fprintf('The FIGURE UI window is already open.\n');
            else
                % If the window is not open, execute the DisplayGUI function
                r = evalin('base', 'r');  % Retrieve r object from the base workspace
                r.make.upload;
                DisplayGUI(r);
                disp('GUI opened')
            end
        end

        % Button pushed function: DebugPortsButton
        function DebugPortsButtonPushed(app, event)
            DebugPorts();
        end

        % Callback function: AbsAnalysisGUIButton, SequenceMakerTextArea
        function AbsAnalysisGUIButtonPushed(app, event)
            %% Abs_Analysis_GUI opening-reopening
            % If 'Abs Analysis Parameters Loader' window exists, close it
            existingWindow1 = findobj('Type', 'figure', 'Name', 'Abs Analysis Parameters Loader', 'Visible', 'on');
            if ~isempty(existingWindow1)
                close(existingWindow1);
            end

            % If 'Abs Analysis GUI Figure' window exists, close it
            existingWindow2 = findobj('Type', 'figure', 'Name', 'Abs Analysis GUI Figure', 'Visible', 'on');
            if ~isempty(existingWindow2)
                close(existingWindow2);
            end

            % If AbsrotionImage class object exists in workspace, delete it
            if evalin('base', 'exist(''AbsrotionImage'', ''var'')')
                evalin('base', 'clear AbsrotionImage');
            end

            % If Abs_Analysis_parameters struct exists in workspace, delete it
            if evalin('base', 'exist(''Abs_Analysis_parameters'', ''var'')')
                evalin('base', 'clear Abs_Analysis_parameters');
            end

            % Open/reopen
            Abs_Analysis_GUI;
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)

            %             [file, path] = uigetfile('*.m');

            defaultPath = 'C:\Users\Apollo\Documents\MATLAB\gravimeter-interface\measurements\';
            [file, ~] = uigetfile(fullfile(defaultPath, '*.m;*.mlx'), 'Select a script');
            %
            if file ~= 0
                % Display the script file name in the Text Area
                app.CallbackfunctionTextArea.Value = file;

                % Update the callback
                [~, scriptName, ext] = fileparts(file); % Extract the file name without extension

                r = evalin('base', 'r');
                if strcmp(ext, '.m')
                    r.callback = str2func(['@', scriptName]);

                elseif strcmp(ext, '.mlx')
                    r.callback = str2func(['@', scriptName]); %huh realised the structure of remote control does not likeczre about t the extension...
                end
                disp('Callback function changed');
                assignin('base', 'r', r);
            end
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            % Retrieve the script name from the text area
            scriptName = app.CallbackfunctionTextArea.Value;

            if iscell(scriptName)
                scriptName = scriptName{1};  % Get the first element if makerName is a cell
            end

            % Check if scriptName is not empty
            if ~isempty(scriptName)
                % Construct the full path to the script
                scriptPath = fullfile('C:\Users\Apollo\Documents\MATLAB\gravimeter-interface\measurements\', scriptName);

                % Open the script in MATLAB editor
                edit(scriptPath);
                disp([scriptName,' opened']);
            else
                disp('No script selected');
            end

        end

        % Button pushed function: InitialiseControlsButton
        function InitialiseControlsButtonPushed(app, event)

            app.RunningLamp.Color = [1 0 0];  % Orange in RGB
            app.RunningLampLabel.Text = 'Initialising';
            app.RunningLampLabel.HorizontalAlignment = 'center';
            app.RunningLampLabel.FontName = 'Chalkboard';
            app.RunningLampLabel.FontSize = 18;
            app.RunningLampLabel.FontColor = [1 1 1];
            app.RunningLampLabel.Position = [924 303 89 32];

            set(app.GravimeterControlInterfaceUIFigure, 'UserData', app);
            % Get all interactive components
            app.components = findall(app.GravimeterControlInterfaceUIFigure, '-property', 'Enable');

            % Disable all interactive components
            set(app.components, 'Enable', 'off');

            set(app.RunningLamp, 'Enable', 'on');
            set(app.RunningLampLabel, 'Enable', 'on');

            % Create timer
            t = timer;
            t.StartDelay = .1;
            t.Period = 0.7;
            t.ExecutionMode = 'fixedRate';
            t.TimerFcn = @(~,~) timerBlink(app);
            start(t);

            % Run InitialiseControls
            pause(1)
            initialiseControls;

            %stop the time and delete it
            stop(t);
            delete(t);  % delete the timer

            % % Get the 'makerCallback' from the 'r' object in the workspace
            r = evalin('base', 'r');  % Retrieve r object from the base workspace
            app.SequenceMakerTextArea.Value = func2str(r.makerCallback);
            if ~isempty(r.callback)
                app.CallbackfunctionTextArea.Value = func2str(r.callback);
            end

            % After InitialiseControls finishes, re-enable all components
            set(app.components, 'Enable', 'on');


            % Disable the MOT switch
            set(app.MOTSwitch, 'Enable', 'off');
            % Turn status lamp to orange and indicate standbye
            app.RunningLamp.Color = [1 0.5 0];  % Orange in RGB
            app.RunningLampLabel.Text = 'Standby';
            app.RunningLampLabel.HorizontalAlignment = 'center';
            app.RunningLampLabel.FontName = 'Chalkboard';
            app.RunningLampLabel.FontSize = 24;
            app.RunningLampLabel.FontColor = [1 1 1];
            app.RunningLampLabel.Position = [924 303 89 32];
        end

        % Button pushed function: ChangeButton
        function ChangeButtonPushed(app, event)

            % Open the uigetfile dialog in the directory
            [fileName, ~] = uigetfile({'*.m;*.mlx'}, 'Select a file', 'C:\Users\Apollo\Documents\MATLAB\gravimeter-interface\Makers');

            if isequal(fileName, 0)
                disp('User selected Cancel');
            else
                % Display the selected file name in the text area
                [~, name, ~] = fileparts(fileName);
                app.SequenceMakerTextArea.Value = name;

                % Change the 'makerCallback' in the 'r' object in the workspace
                r = evalin('base', 'r');
                r.makerCallback = str2func(name);
                assignin('base', 'r', r);

                disp('Sequence Maker changed');
            end


        end

        % Button pushed function: OpenButton_2
        function OpenButton_2Pushed(app, event)
            makerName = app.SequenceMakerTextArea.Value;  % assuming the maker name is stored here
            if iscell(makerName)
                makerName = makerName{1};  % Get the first element if makerName is a cell
            end

            % Check if the file exists with the .m extension
            makerPath_m = fullfile('C:\Users\Apollo\Documents\MATLAB\gravimeter-interface\Makers', [makerName, '.m']);

            % Check if the file exists with the .mlx extension
            makerPath_mlx = fullfile('C:\Users\Apollo\Documents\MATLAB\gravimeter-interface\Makers', [makerName, '.mlx']);

            if isfile(makerPath_m)
                edit(makerPath_m);
                disp([makerName,' opened']);

            elseif isfile(makerPath_mlx)
                edit(makerPath_mlx);
                disp([makerName,' opened']);
            else
                fprintf('Error: File %s or %s does not exist.\n', makerPath_m, makerPath_mlx);
            end


        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
            r = evalin('base', 'r');  % Retrieve r object from the base workspace
            if isempty(r.callback)
                disp('Select a Callback function  first!')
            else
                r.stop;
                r.reset;
                r.start;
                app.disableAllComponentsExceptAbort();
                set(app.StopButton,'Enable','on');
                app.RunningLamp.Color = 'green';
                app.RunningLampLabel.Text = 'Running';
                set(app.RunningLamp,'Enable','on');
                set(app.RunningLampLabel, 'Enable','on');
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            r = evalin('base', 'r');  % Retrieve r object from the base workspace
            r.reset;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            r = evalin('base', 'r');  % Retrieve r object from the base workspace
            r.stop
            enableAllComponents(app);
            % Disable the MOT switch
            set(app.MOTSwitch, 'Enable', 'off');
            value = app.DirectorytypeSwitch.Value;
            if strcmp(value, 'Default')
                set(app.SelectButton_2,'Enable','off')
                set(app.DirectoryEditField,'Editable','off')
            elseif strcmp(value, 'Custom')
                set(app.SelectButton_2,'Enable','on')
                set(app.DirectoryEditField,'Editable','off')
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            r = evalin('base', 'r');  % Retrieve r object from the base workspace
            if isempty(r.callback)
                disp('Select a Callback function first!')
            else
                r.start
                app.disableAllComponentsExceptAbort();
                set(app.StopButton,'Enable','on');
                app.RunningLamp.Color = 'green';
                app.RunningLampLabel.Text = 'Running';
                set(app.RunningLamp,'Enable','on');
                set(app.RunningLampLabel, 'Enable','on');
            end
        end

        % Button pushed function: IncrementButton
        function IncrementButtonPushed(app, event)
            r = evalin('base', 'r');  % Retrieve r object from the base workspace
            if r.c.now == r.c.total
                disp('The counter is currently at the end!')
            else
                r.c.increment;
                cprintf('Keywords','Run incremented from %d/%d to %d\n', r.c.now-1,r.c.total,r.c.now )
            end

        end

        % Button pushed function: DecrementButton
        function DecrementButtonPushed(app, event)
            r = evalin('base', 'r');  % Retrieve r object from the base workspace
            if r.c.now == 1
                disp('The counter is currently at the start!')
            else
                r.c.decrement;
                cprintf('Keywords','Run decremented from %d/%d to %d\n', r.c.now+1,r.c.total,r.c.now )
            end
        end

        % Close request function: GravimeterControlInterfaceUIFigure
        function GravimeterControlInterfaceUIFigureCloseRequest(app, event)
            delete(app) %this close the app!
        end

        % Value changed function: SaveDataSwitch
        function SaveDataSwitchValueChanged(app, event)
            value = app.SaveDataSwitch.Value;
            if strcmp(value, 'On')
                app.StatusLamp_2.Color = [0 1 0]; % Green
            elseif strcmp(value, 'Off')
                app.StatusLamp_2.Color = [1 0.6471 0]; % Orange
            end
        end

        % Button pushed function: SingleRunButton
        function SingleRunButtonPushed(app, event)
            app.disableAllComponentsExceptAbort();

            % Assign a 'false' to 'CycleEnded' in base workspace indicating the cycle hasn't ended yet
            assignin('base', 'CycleEnded', false);

            % Set the lamp color and text to indicate running status
            app.RunningLamp.Color = 'green';
            app.RunningLampLabel.Text = 'Running';
            set(app.RunningLamp,'Enable','on');
            set(app.RunningLampLabel, 'Enable','on');

            % Retrieve r object from the base workspace and make a single run
            r = evalin('base', 'r');
            r.make.urun(@triggerend,@Abs_Analysis_GUI);

            % Now we want to monitor the 'CycleEnded' variable in the base workspace
            app.singleRunTimer = timer('StartDelay', 1, 'Period', 0.5, 'ExecutionMode', 'fixedSpacing', 'TimerFcn', @(~,~)checkRunStatus(app));
            start(app.singleRunTimer); %basically in this step the app will check if cycleended becomes true which is triggered by Abs_Analysis_GUI
        end

        % Button pushed function: ContinuousRunButton
        function ContinuousRunButtonPushed(app, event)

            % disable components
            app.disableAllComponentsExceptAbort();

            r = evalin('base', 'r');  % Retrieve r object from the base workspace
            r.make.upload;
            r.loop(@Abs_Analysis_GUI);

            % Set the lamp color and text to indicate running status
            app.RunningLamp.Color = 'green';
            app.RunningLampLabel.Text = 'Running';
            set(app.RunningLamp,'Enable','on');
            set(app.RunningLampLabel, 'Enable','on');
        end

        % Button pushed function: UploadRunButton
        function UploadRunButtonPushed(app, event)
            r = evalin('base', 'r');  % Retrieve r object from the base workspace
            r.make.upload;
            disp('Run uploaded')
        end

        % Callback function: AbortButton, TimeofflightmsEditField
        function AbortButtonPushed(app, event)

            r = evalin('base', 'r');  % Retrieve r object from the base workspace
            r.stop;
            enableAllComponents(app);
            % Disable the MOT switch
            set(app.MOTSwitch, 'Enable', 'off');
        end

        % Button pushed function: SelectButton_2
        function SelectButton_2Pushed(app, event)

            % Modify the action based on the switch state
            if strcmp(app.DirectorytypeSwitch.Value, 'Default')
                % Change button text
                app.SelectButton_2.Text = 'Open';

                % Open the folder in the file explorer
                winopen('E:\data\');

            elseif strcmp(app.DirectorytypeSwitch.Value, 'Custom')
                % Change button text
                app.SelectButton_2.Text = 'Select';

                % Open a UI to select a folder
                directory = uigetdir('E:\data\', 'Select a directory');

                % Update the DirectoryEditField with the selected directory
                if directory ~= 0
                    app.DirectoryEditField.Value = directory;
                end
            end


        end

        % Button pushed function: ScreenshotButton
        function ScreenshotButtonPushed(app, event)
            topaste;
        end

        % Button pushed function: UploadValuesButton
        function UploadValuesButtonPushed(app, event)
            m = evalin ('base','m');
            m.writeList((const.f_Rb_groundHFS/1e6 + ...
                [app.F1m_f1rightarrowF2m_f0EditField.Value*1e-3,app.F2m_f0rightarrowF1m_f0EditField.Value*1e-6])/2*1e6);
            assignin('base','m',m);
            disp('MW synthesizer new frequencies uploaded')
        end

        % Value changed function: DirectorytypeSwitch
        function DirectorytypeSwitchValueChanged(app, event)
            value = app.DirectorytypeSwitch.Value;
            if strcmp(value, 'Default')
                set(app.SelectButton_2,'Enable','on')
                set(app.DirectoryEditField,'Editable','off')
                app.SelectButton_2.Text = 'Open';

            elseif strcmp(value, 'Custom')
                set(app.SelectButton_2,'Enable','on')
                set(app.DirectoryEditField,'Editable','off')
                app.SelectButton_2.Text = 'Select';

            end
        end

        % Button pushed function: ManualsaveButton
        function ManualsaveButtonPushed(app, event)
            app.RunningLamp.Color = 'blue';
            app.RunningLampLabel.Text = 'Saving';

            r = evalin('base','r');
            data = r.data;
            pause(.3)
            assignin('base','data',data);

            % Get current date and time
            dateTimeNow = datestr(datetime('now'),'yy-mm-dd-HH-MM-SS');

            if strcmp(app.DirectorytypeSwitch.Value, 'Default')
                % Create the directory
                directory = ['E:\data\', datestr(datetime('now'),'yy-mm-dd')];
                if ~exist(directory, 'dir')
                    mkdir(directory)
                end

                % Get the filename
                filename = getFileName(app, directory);

            elseif strcmp(app.DirectorytypeSwitch.Value, 'Custom')
                directory = app.DirectoryEditField.Value;
                if isempty(directory)
                    directory = uigetdir('E:\data\', 'Select a directory');
                    % Update the DirectoryEditField with the selected directory
                    app.DirectoryEditField.Value = directory;
                end

                filename = getFileName(app, directory);
            end

            % Save the file
            filename = [directory, '\', dateTimeNow, '_', filename, '.mat'];
            save(filename, 'data');
            disp(['File ', filename, ' was saved in ', directory])
            app.RunningLamp.Color = [1 0.5 0];  % Orange in RGB
            app.RunningLampLabel.Text = 'Standby';
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            disp('Silly me! there is no help yet, ask Yosri!')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.GravimeterControlInterfaceUIFigure = uifigure('Visible', 'off');
            app.GravimeterControlInterfaceUIFigure.Name = 'Gravimeter Control Interface';
            app.GravimeterControlInterfaceUIFigure.Icon = fullfile(pathToMLAPP, 'spatialfringes.png');
            app.GravimeterControlInterfaceUIFigure.CloseRequestFcn = createCallbackFcn(app, @GravimeterControlInterfaceUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.GravimeterControlInterfaceUIFigure);
            app.CoolingTab = uitab(app.TabGroup);
            app.CoolingTab.Title = 'Cooling';
            app.DetuningMHzLabel = uilabel(app.CoolingTab);
            app.DetuningMHzLabel.Text = 'Detuning (MHz)';
            app.DetuningMHzEditField = uieditfield(app.CoolingTab, 'numeric');
            app.DetuningMHzEditField.Limits = [-23 23];
            app.TimeofflightmsEditFieldLabel = uilabel(app.CoolingTab);
            app.TimeofflightmsEditFieldLabel.Text = 'Time of flight (ms)';
            app.TimeofflightmsEditField = uieditfield(app.CoolingTab, 'numeric');
            app.TimeofflightmsEditField.Limits = [0 800];
            app.TimeofflightmsEditField.ValueChangedFcn = createCallbackFcn(app, @AbortButtonPushed, true);
            app.TimeofflightmsEditField.Value = 216;
            app.DipolesWEditFieldLabel = uilabel(app.CoolingTab);
            app.DipolesWEditFieldLabel.Text = 'Dipoles (W)';
            app.DipolesWEditField = uieditfield(app.CoolingTab, 'numeric');
            app.DipolesWEditField.Limits = [0 25];
            app.DipolesWEditField.Value = 2.5;
            app.UploadOptionsButton = uibutton(app.CoolingTab, 'push');
            app.UploadOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @UploadOptionsButtonPushed, true);
            app.UploadOptionsButton.Text = 'Upload Options';
            app.OptionsLabel = uilabel(app.CoolingTab);
            app.OptionsLabel.Text = 'Options';
            app.CoolingSequenceLabel = uilabel(app.CoolingTab);
            app.CoolingSequenceLabel.Text = 'Cooling Sequence';
            app.MOTSwitchLabel = uilabel(app.CoolingTab);
            app.MOTSwitchLabel.Text = 'MOT';
            app.MOTSwitch = uiswitch(app.CoolingTab, 'slider');
            app.MOTSwitch.ValueChangedFcn = createCallbackFcn(app, @MOTSwitchValueChanged, true);
            app.MOTSwitch.Enable = 'off';
            app.MOTSwitch.Value = 'On';
            app.CMOTSwitchLabel = uilabel(app.CoolingTab);
            app.CMOTSwitchLabel.Text = 'CMOT';
            app.CMOTSwitch = uiswitch(app.CoolingTab, 'slider');
            app.CMOTSwitch.ValueChangedFcn = createCallbackFcn(app, @CMOTSwitchValueChanged, true);
            app.CMOTSwitch.Value = 'On';
            app.PGCSwitchLabel = uilabel(app.CoolingTab);
            app.PGCSwitchLabel.Text = 'PGC';
            app.PGCSwitch = uiswitch(app.CoolingTab, 'slider');
            app.PGCSwitch.ValueChangedFcn = createCallbackFcn(app, @PGCSwitchValueChanged, true);
            app.PGCSwitch.Value = 'On';
            app.LoadMagTrapSwitchLabel = uilabel(app.CoolingTab);
            app.LoadMagTrapSwitchLabel.Text = 'Load Mag Trap';
            app.LoadMagTrapSwitch = uiswitch(app.CoolingTab, 'slider');
            app.LoadMagTrapSwitch.ValueChangedFcn = createCallbackFcn(app, @LoadMagTrapSwitchValueChanged, true);
            app.LoadMagTrapSwitch.Value = 'On';
            app.MagEvaporationSwitchLabel = uilabel(app.CoolingTab);
            app.MagEvaporationSwitchLabel.Text = 'Mag Evaporation';
            app.MagEvaporationSwitch = uiswitch(app.CoolingTab, 'slider');
            app.MagEvaporationSwitch.ValueChangedFcn = createCallbackFcn(app, @MagEvaporationSwitchValueChanged, true);
            app.MagEvaporationSwitch.Value = 'On';
            app.LoadOpticalTrapSwitchLabel = uilabel(app.CoolingTab);
            app.LoadOpticalTrapSwitchLabel.Text = 'Load Optical Trap';
            app.LoadOpticalTrapSwitch = uiswitch(app.CoolingTab, 'slider');
            app.LoadOpticalTrapSwitch.ValueChangedFcn = createCallbackFcn(app, @LoadOpticalTrapSwitchValueChanged, true);
            app.LoadOpticalTrapSwitch.Value = 'On';
            app.OpticalEvaporationSwitchLabel = uilabel(app.CoolingTab);
            app.OpticalEvaporationSwitchLabel.Text = 'Optical Evaporation';
            app.OpticalEvaporationSwitch = uiswitch(app.CoolingTab, 'slider');
            app.OpticalEvaporationSwitch.ValueChangedFcn = createCallbackFcn(app, @OpticalEvaporationSwitchValueChanged, true);
            app.OpticalEvaporationSwitch.Value = 'On';
            app.BECCompressionSwitchLabel = uilabel(app.CoolingTab);
            app.BECCompressionSwitchLabel.Text = 'BEC Compression';
            app.BECCompressionSwitch = uiswitch(app.CoolingTab, 'slider');
            app.BECCompressionSwitch.ValueChangedFcn = createCallbackFcn(app, @BECCompressionSwitchValueChanged, true);
            app.MagneticInsenstivestateTransferSwitchLabel = uilabel(app.CoolingTab);
            app.MagneticInsenstivestateTransferSwitchLabel.Text = 'Magnetic Insenstive state Transfer';
            app.MagneticInsenstivestateTransferSwitch = uiswitch(app.CoolingTab, 'slider');
            app.MagneticInsenstivestateTransferSwitch.ValueChangedFcn = createCallbackFcn(app, @MagneticInsenstivestateTransferSwitchValueChanged, true);
            app.SingleRunButton = uibutton(app.CoolingTab, 'push');
            app.SingleRunButton.ButtonPushedFcn = createCallbackFcn(app, @SingleRunButtonPushed, true);
            app.SingleRunButton.Text = 'Single Run';
            app.VariableParameterEditFieldLabel = uilabel(app.CoolingTab);
            app.VariableParameterEditFieldLabel.Text = 'Variable Parameter';
            app.VariableParameterEditField = uieditfield(app.CoolingTab, 'numeric');
            app.ketF1m_f1rightarrowLabel = uilabel(app.CoolingTab);
            app.ketF1m_f1rightarrowLabel.Interpreter = 'latex';
            app.ketF1m_f1rightarrowLabel.Text = '$|F =1, m_f = -1> \rightarrow |F =2, m_f = 0> $ ';
            app.F1m_f1rightarrowF2m_f0EditField = uieditfield(app.CoolingTab, 'numeric');
            app.F1m_f1rightarrowF2m_f0EditField.Limits = [-500 500];
            app.F2m_f0rightarrowF1m_f0EditFieldLabel = uilabel(app.CoolingTab);
            app.F2m_f0rightarrowF1m_f0EditFieldLabel.Interpreter = 'latex';
            app.F2m_f0rightarrowF1m_f0EditFieldLabel.Text = '$|F =2, m_f = 0> \rightarrow |F =1, m_f = 0> $ ';
            app.F2m_f0rightarrowF1m_f0EditField = uieditfield(app.CoolingTab, 'numeric');
            app.F2m_f0rightarrowF1m_f0EditField.Limits = [-200 200];
            app.RunningLamp = uilamp(app.CoolingTab);
            app.ToolsAccessLabel = uilabel(app.CoolingTab);
            app.ToolsAccessLabel.Text = 'Tools Access';
            app.RunexecutionLabel = uilabel(app.CoolingTab);
            app.RunexecutionLabel.Text = 'Run execution';
            app.CallbackfunctionLabel = uilabel(app.CoolingTab);
            app.CallbackfunctionLabel.Text = 'Callback function';
            app.ChangeButton = uibutton(app.CoolingTab, 'push');
            app.ChangeButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeButtonPushed, true);
            app.ChangeButton.Text = 'Change';
            app.MOTLoadtimesEditFieldLabel = uilabel(app.CoolingTab);
            app.MOTLoadtimesEditFieldLabel.Text = 'MOT Load time (s)';
            app.MOTLoadtimesEditField = uieditfield(app.CoolingTab, 'numeric');
            app.MOTLoadtimesEditField.Limits = [0 30];
            app.MOTLoadtimesEditField.Value = 4;
            app.StartButton = uibutton(app.CoolingTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ContinuousRunButton = uibutton(app.CoolingTab, 'push');
            app.ContinuousRunButton.ButtonPushedFcn = createCallbackFcn(app, @ContinuousRunButtonPushed, true);
            app.ContinuousRunButton.Text = 'Continuous Run';
            app.StopButton = uibutton(app.CoolingTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ResetButton = uibutton(app.CoolingTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.IncrementButton = uibutton(app.CoolingTab, 'push');
            app.IncrementButton.ButtonPushedFcn = createCallbackFcn(app, @IncrementButtonPushed, true);
            app.IncrementButton.Text = 'Increment';
            app.DecrementButton = uibutton(app.CoolingTab, 'push');
            app.DecrementButton.ButtonPushedFcn = createCallbackFcn(app, @DecrementButtonPushed, true);
            app.DecrementButton.Text = 'Decrement';
            app.RestartButton = uibutton(app.CoolingTab, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Text = 'Restart';
            app.SaveDataSwitchLabel = uilabel(app.CoolingTab);
            app.SaveDataSwitchLabel.Text = 'Save Data';
            app.SaveDataSwitch = uiswitch(app.CoolingTab, 'toggle');
            app.SaveDataSwitch.ValueChangedFcn = createCallbackFcn(app, @SaveDataSwitchValueChanged, true);
            app.StatusLamp_2 = uilamp(app.CoolingTab);
            app.DirectoryEditFieldLabel = uilabel(app.CoolingTab);
            app.DirectoryEditFieldLabel.Text = 'Directory';
            app.DirectoryEditField = uieditfield(app.CoolingTab, 'text');
            app.NameEditFieldLabel = uilabel(app.CoolingTab);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.CoolingTab, 'text');
            app.ScreenshotButton = uibutton(app.CoolingTab, 'push');
            app.ScreenshotButton.ButtonPushedFcn = createCallbackFcn(app, @ScreenshotButtonPushed, true);
            app.ScreenshotButton.Text = {'Screenshot!'; '📸'};
            app.UploadRunButton = uibutton(app.CoolingTab, 'push');
            app.UploadRunButton.ButtonPushedFcn = createCallbackFcn(app, @UploadRunButtonPushed, true);
            app.UploadRunButton.Text = 'Upload Run';
            app.UploadValuesButton = uibutton(app.CoolingTab, 'push');
            app.UploadValuesButton.ButtonPushedFcn = createCallbackFcn(app, @UploadValuesButtonPushed, true);
            app.UploadValuesButton.Text = 'Upload Values';
            app.MTSLockingButton = uibutton(app.CoolingTab, 'push');
            app.MTSLockingButton.ButtonPushedFcn = createCallbackFcn(app, @MTSLockingButtonPushed, true);
            app.MTSLockingButton.Text = 'MTS Locking';
            app.DisplaySequenceGUIButton = uibutton(app.CoolingTab, 'push');
            app.DisplaySequenceGUIButton.ButtonPushedFcn = createCallbackFcn(app, @DisplaySequenceGUIButtonPushed, true);
            app.DisplaySequenceGUIButton.Text = 'Display Sequence GUI';
            app.DebugPortsButton = uibutton(app.CoolingTab, 'push');
            app.DebugPortsButton.ButtonPushedFcn = createCallbackFcn(app, @DebugPortsButtonPushed, true);
            app.DebugPortsButton.Text = 'Debug Ports';
            app.AbsAnalysisGUIButton = uibutton(app.CoolingTab, 'push');
            app.AbsAnalysisGUIButton.ButtonPushedFcn = createCallbackFcn(app, @AbsAnalysisGUIButtonPushed, true);
            app.AbsAnalysisGUIButton.Text = 'Abs Analysis GUI';
            app.AbortButton = uibutton(app.CoolingTab, 'push');
            app.AbortButton.ButtonPushedFcn = createCallbackFcn(app, @AbortButtonPushed, true);
            app.AbortButton.Text = 'Abort';
            app.CallbackfunctionLabel_2 = uilabel(app.CoolingTab);
            app.CallbackfunctionLabel_2.Text = 'Callback function:';
            app.CallbackfunctionTextArea = uitextarea(app.CoolingTab);
            app.CallbackfunctionTextArea.Editable = 'off';
            app.SelectButton = uibutton(app.CoolingTab, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.OpenButton = uibutton(app.CoolingTab, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.SequenceMakerLabel = uilabel(app.CoolingTab);
            app.SequenceMakerLabel.Text = 'Sequence Maker:';
            app.SequenceMakerTextArea = uitextarea(app.CoolingTab);
            app.SequenceMakerTextArea.ValueChangedFcn = createCallbackFcn(app, @AbsAnalysisGUIButtonPushed, true);
            app.SequenceMakerTextArea.Editable = 'off';
            app.OpenButton_2 = uibutton(app.CoolingTab, 'push');
            app.OpenButton_2.ButtonPushedFcn = createCallbackFcn(app, @OpenButton_2Pushed, true);
            app.OpenButton_2.Text = 'Open';
            app.TextArea = uitextarea(app.CoolingTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'350±30 kHz'};
            app.TextArea_2 = uitextarea(app.CoolingTab);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'69±30 Hz'};
            app.RunningLampLabel = uilabel(app.CoolingTab);
            app.RunningLampLabel.Text = 'Running';
            app.SelectButton_2 = uibutton(app.CoolingTab, 'push');
            app.SelectButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_2Pushed, true);
            app.SelectButton_2.Text = 'Select';
            app.ManualsaveButton = uibutton(app.CoolingTab, 'push');
            app.ManualsaveButton.ButtonPushedFcn = createCallbackFcn(app, @ManualsaveButtonPushed, true);
            app.ManualsaveButton.Text = 'Manual save';
            app.HelpButton = uibutton(app.CoolingTab, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help?';
            app.DirectorytypeSwitchLabel = uilabel(app.CoolingTab);
            app.DirectorytypeSwitchLabel.Text = 'Directory type';
            app.DirectorytypeSwitch = uiswitch(app.CoolingTab, 'toggle');
            app.DirectorytypeSwitch.Items = {'Default', 'Custom'};
            app.DirectorytypeSwitch.ValueChangedFcn = createCallbackFcn(app, @DirectorytypeSwitchValueChanged, true);
            app.DirectorytypeSwitch.Value = 'Default';
            app.InitialiseControlsButton = uibutton(app.CoolingTab, 'push');
            app.InitialiseControlsButton.ButtonPushedFcn = createCallbackFcn(app, @InitialiseControlsButtonPushed, true);
            app.InitialiseControlsButton.Text = 'Initialise Controls';
            app.InterferometryTab = uitab(app.TabGroup);
            app.InterferometryTab.Title = 'Interferometry';
            app.ComingSoonLabel_2 = uilabel(app.InterferometryTab);
            app.ComingSoonLabel_2.Text = 'Coming Soon...';
            app.GravimeterControlInterfaceLabel = uilabel(app.GravimeterControlInterfaceUIFigure);
            app.GravimeterControlInterfaceLabel.Text = 'Gravimeter Control Interface';
            % Show the figure after all components are created
            app.GravimeterControlInterfaceUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GravimeterControlInterface

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.GravimeterControlInterfaceUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.GravimeterControlInterfaceUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GravimeterControlInterfaceUIFigure)
        end
    end
end