classdef DisplayGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        PlotVoltsCheckBox            matlab.ui.control.CheckBox
        DDSScaleEditField            matlab.ui.control.NumericEditField
        DDSScaleEditFieldLabel       matlab.ui.control.Label
        DDSChannelsListBox           matlab.ui.control.ListBox
        DDSChannelsListBoxLabel      matlab.ui.control.Label
        AnalogOffsetEditField        matlab.ui.control.NumericEditField
        AnalogOffsetEditFieldLabel   matlab.ui.control.Label
        DigitalOffsetEditField       matlab.ui.control.NumericEditField
        DigitalOffsetEditFieldLabel  matlab.ui.control.Label
        AnalogChannelsListBox        matlab.ui.control.ListBox
        AnalogChannelsListBoxLabel   matlab.ui.control.Label
        DigitalChannelsListBox       matlab.ui.control.ListBox
        DigitalChannelsListBoxLabel  matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        r % Description
        el % Description
    end
    
    methods (Access = public)
        
        function createListBoxItems(app)
            app.DigitalChannelsListBox.Items = {};
            app.DigitalChannelsListBox.ItemsData = {};
            app.AnalogChannelsListBox.Items = {};
            app.AnalogChannelsListBox.ItemsData = {};
            app.DDSChannelsListBox.Items = {};
            app.DDSChannelsListBox.ItemsData = {};
            if isempty(app.r.sq)
                app.r.sq = initSequence;
            end
%             pbhv.exitFcn = @(hfig,cpp) set(app.CurrentPlotEditField,'value','');
            pbhv.exitFcn = @(hfig,cpp) set(app.UIAxes.Title,'String','All Channels','Color','k');
            pbhv.traverseFcn = [];
%             boxargs = {'fitboxtotext','on','margin',0,'linestyle','none'};
            hold(app.UIAxes,'on');
            set(app.UIAxes,'XGrid','on','YGrid','on');
            if app.PlotVoltsCheckBox.Value
                plot_volts = 1;
            else
                plot_volts = 0;
            end
            ch_plot_args = {'finaltime',app.r.sq.latest,'returnhandle',true,'plotvolts',plot_volts,'plotargs',{'visible','off'}};
            clrs = get(app.UIAxes,'colororder');
            clrs = [clrs;circshift(clrs,2,1);circshift(clrs,2,2)];
            clrs = [clrs;0.5*clrs];
            nn = 1;
            for ch = app.r.sq.digital()
                if ~isempty(ch.name)
                    plot_args = {'visible','off','color',clrs(nn,:)};
                    nn = nn + 1;
                    ch_plot_args{end} = plot_args;
                    lname = sprintf('%s',ch.name);
                    app.DigitalChannelsListBox.Items{end+1} = lname;
                    h = ch.plot(app.UIAxes,ch_plot_args{:});
                    app.DigitalChannelsListBox.ItemsData{end+1} = h;
%                     pbhv.enterFcn = @(hfig,cpp) set(hfig,'userdata',...
%                         annotation(hfig,'textbox',[[cpp(1),cpp(2)]./hfig.Position(3:4),0.05,0.05],'String',ch.name,'color',h.Color,boxargs{:}));
                    pbhv.enterFcn = @(hfig,cpp) set(app.UIAxes.Title,'String',lname,'Color',h.Color);
                    iptSetPointerBehavior(h,pbhv);
                end
            end
            nn = 1;
            for ch = app.r.sq.analog()
                if ~isempty(ch.name)
                    plot_args = {'visible','off','color',clrs(nn,:)};
                    nn = nn + 1;
                    ch_plot_args{end} = plot_args;
                    lname = ch.label_with_units;
                    app.AnalogChannelsListBox.Items{end+1} = lname;
                    h = ch.plot(app.UIAxes,ch_plot_args{:});
                    app.AnalogChannelsListBox.ItemsData{end+1} = h;
%                     pbhv.enterFcn = @(hfig,cpp) set(hfig,'userdata',...
%                         annotation(hfig,'textbox',[[cpp(1),cpp(2)]./hfig.Position(3:4),0.05,0.05],'String',ch.name,'color',h.Color,boxargs{:}));
                    pbhv.enterFcn = @(hfig,cpp) set(app.UIAxes.Title,'String',lname,'Color',h.Color);
                    iptSetPointerBehavior(h,pbhv);
                end
            end
            ddsfields = {'Freq','Amp','Phase'};
            for ch = app.r.sq.dds
                if ~isempty(ch.name)
                    for nn = 1:numel(ddsfields)
                        lname = sprintf('%s %s',ch.name,ddsfields{nn});
                        app.DDSChannelsListBox.Items{end+1} = lname;
                        h = ch.plot(app.UIAxes,'plotidx',nn,plot_args{:});
                        app.DDSChannelsListBox.ItemsData{end+1} = h;
                        pbhv.enterFcn = @(hfig,cpp) set(app.UIAxes.Title,'String',lname,'Color',h.Color);
                        iptSetPointerBehavior(h,pbhv);
                    end
                end
                
            end
            
            iptPointerManager(app.UIFigure,'enable');
%             addlistener(app.r,'sequenceChanged',@app.updatePlot);
            app.el = listener(app.r,'sequenceChanged',@app.updatePlot);
            
        end
    end
    
    methods (Access = private)
        
        function updatePlot(app,~,~)
            app.DigitalChannelsListBoxValueChanged;
            app.AnalogChannelsListBoxValueChanged;
            app.DDSChannelsListBoxValueChanged;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, r)
            app.r = r;
            app.createListBoxItems;
        end

        % Value changed function: DigitalChannelsListBox
        function DigitalChannelsListBoxValueChanged(app, event)
%             value = app.DigitalChannelsListBox.Value;
            if app.PlotVoltsCheckBox.Value
                plot_volts = 1;
            else
                plot_volts = 0;
            end
            lastTime = app.r.sq.latest;
            for nn = 1:numel(app.DigitalChannelsListBox.Items)
                [x,y] = app.r.sq.find(app.DigitalChannelsListBox.Items{nn}).getPlotValues('finaltime',lastTime,'returnhandle',true,'plotvolts',plot_volts);
                set(app.DigitalChannelsListBox.ItemsData{nn},'visible','off','xdata',x,'ydata',y);
            end
            for nn = 1:numel(app.DigitalChannelsListBox.Value)
                h = app.DigitalChannelsListBox.Value{nn};
                set(h,'visible','on','YData',h.YData+(nn-1)*app.DigitalOffsetEditField.Value);
            end
        end

        % Value changed function: AnalogChannelsListBox
        function AnalogChannelsListBoxValueChanged(app, event)
%             value = app.AnalogChannelsListBox.Value;
            if app.PlotVoltsCheckBox.Value
                plot_volts = 1;
            else
                plot_volts = 0;
            end
            lastTime = app.r.sq.latest;
            for nn = 1:numel(app.AnalogChannelsListBox.Items)
                [x,y] = app.r.sq.find(app.AnalogChannelsListBox.Items{nn}).getPlotValues('finaltime',lastTime,'returnhandle',true,'plotvolts',plot_volts);
                set(app.AnalogChannelsListBox.ItemsData{nn},'visible','off','xdata',x,'ydata',y);
            end
            for nn = 1:numel(app.AnalogChannelsListBox.Value)
                h = app.AnalogChannelsListBox.Value{nn};
                set(h,'visible','on','ydata',h.YData + (nn-1)*app.AnalogOffsetEditField.Value);
            end
        end

        % Value changed function: DigitalOffsetEditField
        function DigitalOffsetEditFieldValueChanged(app, event)
%             value = app.DigitalOffsetEditField.Value;
            app.DigitalChannelsListBoxValueChanged;
        end

        % Value changed function: AnalogOffsetEditField
        function AnalogOffsetEditFieldValueChanged(app, event)
%             value = app.AnalogOffsetEditField.Value;
            app.AnalogChannelsListBoxValueChanged;
        end

        % Value changed function: DDSChannelsListBox
        function DDSChannelsListBoxValueChanged(app, event)
%             value = app.DDSChannelsListBox.Value;
            ddsfields = {'Freq','Amp','Phase'};
            lastTime = app.r.sq.latest;
            for nn = 1:numel(app.r.sq.dds)
                for mm = 1:numel(ddsfields)
                    [x,y] = app.r.sq.dds(nn).getPlotValues('finaltime',lastTime,'returnhandle',true,'plotidx',mm);
                    y = y*app.DDSScaleEditField.Value;
                    set(app.DDSChannelsListBox.ItemsData{(nn-1)*numel(ddsfields)+mm},'visible','off','xdata',x,'ydata',y);
                end
            end
            for nn = 1:numel(app.DDSChannelsListBox.Value)
                h = app.DDSChannelsListBox.Value{nn};
                set(h,'visible','on');
            end
        end

        % Value changed function: DDSScaleEditField
        function DDSScaleEditFieldValueChanged(app, event)
%             value = app.DDSScaleEditField.Value;
            app.DDSChannelsListBoxValueChanged;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.el);
            delete(app)
            
        end

        % Value changed function: PlotVoltsCheckBox
        function PlotVoltsCheckBoxValueChanged(app, event)
%             value = app.PlotVoltsCheckBox.Value;
            app.updatePlot;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'All Channels')
            xlabel(app.UIAxes, 'Time [s]')
            ylabel(app.UIAxes, 'Value')
            app.DigitalChannelsListBoxLabel = uilabel(app.UIFigure);
            app.DigitalChannelsListBoxLabel.Text = 'Digital Channels';
            app.DigitalChannelsListBox = uilistbox(app.UIFigure);
            app.DigitalChannelsListBox.Multiselect = 'on';
            app.DigitalChannelsListBox.ValueChangedFcn = createCallbackFcn(app, @DigitalChannelsListBoxValueChanged, true);
            app.DigitalChannelsListBox.Value = {'Item 3'};
            app.AnalogChannelsListBoxLabel = uilabel(app.UIFigure);
            app.AnalogChannelsListBoxLabel.Text = 'Analog Channels';
            app.AnalogChannelsListBox = uilistbox(app.UIFigure);
            app.AnalogChannelsListBox.Multiselect = 'on';
            app.AnalogChannelsListBox.ValueChangedFcn = createCallbackFcn(app, @AnalogChannelsListBoxValueChanged, true);
            app.AnalogChannelsListBox.Value = {'Item 3'};
            app.DigitalOffsetEditFieldLabel = uilabel(app.UIFigure);
            app.DigitalOffsetEditFieldLabel.Text = 'Digital Offset';
            app.DigitalOffsetEditField = uieditfield(app.UIFigure, 'numeric');
            app.DigitalOffsetEditField.ValueChangedFcn = createCallbackFcn(app, @DigitalOffsetEditFieldValueChanged, true);
            app.AnalogOffsetEditFieldLabel = uilabel(app.UIFigure);
            app.AnalogOffsetEditFieldLabel.Text = 'Analog Offset';
            app.AnalogOffsetEditField = uieditfield(app.UIFigure, 'numeric');
            app.AnalogOffsetEditField.ValueChangedFcn = createCallbackFcn(app, @AnalogOffsetEditFieldValueChanged, true);
            app.DDSChannelsListBoxLabel = uilabel(app.UIFigure);
            app.DDSChannelsListBoxLabel.Text = 'DDS Channels';
            app.DDSChannelsListBox = uilistbox(app.UIFigure);
            app.DDSChannelsListBox.Multiselect = 'on';
            app.DDSChannelsListBox.ValueChangedFcn = createCallbackFcn(app, @DDSChannelsListBoxValueChanged, true);
            app.DDSChannelsListBox.Value = {'Item 3'};
            app.DDSScaleEditFieldLabel = uilabel(app.UIFigure);
            app.DDSScaleEditFieldLabel.Text = 'DDS Scale';
            app.DDSScaleEditField = uieditfield(app.UIFigure, 'numeric');
            app.DDSScaleEditField.ValueChangedFcn = createCallbackFcn(app, @DDSScaleEditFieldValueChanged, true);
            app.DDSScaleEditField.Value = 1;
            app.PlotVoltsCheckBox = uicheckbox(app.UIFigure);
            app.PlotVoltsCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotVoltsCheckBoxValueChanged, true);
            app.PlotVoltsCheckBox.Text = 'Plot Volts?';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DisplayGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end